classdef J4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StartButton              matlab.ui.control.Button
        FrameEditFieldLabel      matlab.ui.control.Label
        FrameEditField           matlab.ui.control.NumericEditField
        TimeEditFieldLabel       matlab.ui.control.Label
        TimeEditFieldReal        matlab.ui.control.NumericEditField
        StatusLampLabel          matlab.ui.control.Label
        StatusLamp               matlab.ui.control.Lamp
        TextArea                 matlab.ui.control.TextArea
        TaskAMedicinePanel       matlab.ui.container.Panel
        XEditFieldLabel          matlab.ui.control.Label
        XEditFieldA              matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        YEditFieldA              matlab.ui.control.NumericEditField
        WidthEditFieldLabel      matlab.ui.control.Label
        WidthEditFieldA          matlab.ui.control.NumericEditField
        HightEditFieldLabel      matlab.ui.control.Label
        HightEditFieldA          matlab.ui.control.NumericEditField
        SwitchA                  matlab.ui.control.ToggleSwitch
        TimeEditField_2Label     matlab.ui.control.Label
        TimeEditFieldA           matlab.ui.control.NumericEditField
        JValueLabel              matlab.ui.control.Label
        JValueA                  matlab.ui.control.NumericEditField
        TaskBBathroomPanel       matlab.ui.container.Panel
        XEditField_3Label        matlab.ui.control.Label
        XEditFieldB              matlab.ui.control.NumericEditField
        YEditField_2Label        matlab.ui.control.Label
        YEditFieldB              matlab.ui.control.NumericEditField
        WidthEditField_2Label    matlab.ui.control.Label
        WidthEditFieldB          matlab.ui.control.NumericEditField
        HightEditField_2Label    matlab.ui.control.Label
        HightEditFieldB          matlab.ui.control.NumericEditField
        SwitchB                  matlab.ui.control.ToggleSwitch
        TimeEditField_3Label     matlab.ui.control.Label
        TimeEditFieldB           matlab.ui.control.NumericEditField
        JValueLabel_2            matlab.ui.control.Label
        JValueB                  matlab.ui.control.NumericEditField
        TaskCBedPanel            matlab.ui.container.Panel
        XEditField_3Label_2      matlab.ui.control.Label
        XEditFieldC              matlab.ui.control.NumericEditField
        YEditField_2Label_2      matlab.ui.control.Label
        YEditFieldC              matlab.ui.control.NumericEditField
        WidthEditField_2Label_2  matlab.ui.control.Label
        WidthEditFieldC          matlab.ui.control.NumericEditField
        HightEditField_2Label_2  matlab.ui.control.Label
        HightEditFieldC          matlab.ui.control.NumericEditField
        SwitchC                  matlab.ui.control.ToggleSwitch
        TimeEditField_3Label_2   matlab.ui.control.Label
        TimeEditFieldC           matlab.ui.control.NumericEditField
        JValueLabel_3            matlab.ui.control.Label
        JValueC                  matlab.ui.control.NumericEditField
        TaskDEntrancePanel       matlab.ui.container.Panel
        XEditField_3Label_3      matlab.ui.control.Label
        XEditFieldD              matlab.ui.control.NumericEditField
        YEditField_2Label_3      matlab.ui.control.Label
        YEditFieldD              matlab.ui.control.NumericEditField
        WidthEditField_2Label_3  matlab.ui.control.Label
        WidthEditFieldD          matlab.ui.control.NumericEditField
        HightEditField_2Label_3  matlab.ui.control.Label
        HightEditFieldD          matlab.ui.control.NumericEditField
        SwitchD                  matlab.ui.control.ToggleSwitch
        TimeEditField_3Label_3   matlab.ui.control.Label
        TimeEditFieldD           matlab.ui.control.NumericEditField
        JValueLabel_4            matlab.ui.control.Label
        JValueD                  matlab.ui.control.NumericEditField
        JValueEditFieldLabel     matlab.ui.control.Label
        JValueEditField          matlab.ui.control.NumericEditField
        HeadCountEditFieldLabel  matlab.ui.control.Label
        HeadCountEditField       matlab.ui.control.NumericEditField
        PositionTextAreaLabel    matlab.ui.control.Label
        PositionTextArea         matlab.ui.control.TextArea
        UIAxes                   matlab.ui.control.UIAxes
        MedicineEditFieldLabel   matlab.ui.control.Label
        Medicine                 matlab.ui.control.NumericEditField
        ActiveEditFieldLabel     matlab.ui.control.Label
        Active                   matlab.ui.control.NumericEditField
        M                        matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        Property % Description
        x = 0;
        JM = 0;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Pixel values of medicine table
            app.XEditFieldA.Value = 539;
            app.YEditFieldA.Value = 356;
            app.WidthEditFieldA.Value = 100;
            app.HightEditFieldA.Value = 124;
            app.JValueA.Value = 124;
            % Pixel values of bathroom door
            app.XEditFieldB.Value = 177;
            app.YEditFieldB.Value = 121;
            app.WidthEditFieldB.Value = 200;
            app.HightEditFieldB.Value = 359;
            app.JValueB.Value = 124;
            % Pixel values of bed
            app.XEditFieldC.Value = 1;
            app.YEditFieldC.Value = 411;
            app.WidthEditFieldC.Value = 50;
            app.HightEditFieldC.Value = 63;
            app.JValueC.Value = 124;
            % Pixel values of entrance
            app.XEditFieldD.Value = 515;
            app.YEditFieldD.Value = 130;
            app.WidthEditFieldD.Value = 115;
            app.HightEditFieldD.Value = 350;
            app.JValueD.Value = 124;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            countBathroom = 0;
            countBed = 0;
            lastPos = 0;
            active = 0;
            app.StatusLamp.Color = 'green';
            app.TextArea.Value = "";
            cam = webcam(1);
            medicine = [app.XEditFieldA.Value, app.YEditFieldA.Value, app.WidthEditFieldA.Value, app.HightEditFieldA.Value];
            medicineJ = app.JValueA.Value;
            medicineCor = [medicine(1,1),medicine(1,2),medicine(1,1)+medicine(1,3),medicine(1,2)+medicine(1,4)];
            barthRoom = [app.XEditFieldB.Value, app.YEditFieldB.Value, app.WidthEditFieldB.Value, app.HightEditFieldB.Value];
            barthRoomJ = app.JValueB.Value;
            barthRoomCor = [barthRoom(1,1),barthRoom(1,2),barthRoom(1,1)+barthRoom(1,3),barthRoom(1,2)+barthRoom(1,4)];
            bed = [app.XEditFieldC.Value, app.YEditFieldC.Value, app.WidthEditFieldC.Value, app.HightEditFieldC.Value];
            bedJ = app.JValueC.Value;
            bedCor = [bed(1,1),bed(1,2),bed(1,1)+bed(1,3),bed(1,2)+bed(1,4)];
            entrance = [app.XEditFieldD.Value, app.YEditFieldD.Value, app.WidthEditFieldD.Value, app.HightEditFieldD.Value];
            entranceJ = app.JValueD.Value;
            entranceCor = [entrance(1,1),entrance(1,2),entrance(1,1)+entrance(1,3),entrance(1,2)+entrance(1,4)];
            medicineTake = 0;
            
            app.M.Value=1;
            app.JM=app.M.Value;
            app.x=0;
            
            while(app.x<300)
                
                app.x = app.x+1;
                app.FrameEditField.Value = app.x;
                a = clock;
                time = a(4)+(a(5)/100);
                app.TimeEditFieldReal.Value = time;
                
                app.JM=app.M.Value;
                if app.JM==0
                    app.x = 300;
                end
                
                app.Medicine.Value = medicineTake;
                app.Active.Value = active;
                
                if(app.TimeEditFieldA.Value<time && (app.TimeEditFieldA.Value+0.15)>time && medicineTake == 0)
                    app.StatusLamp.Color = 'red';
                    app.TextArea.Value = "You didn't take medicine";
                end
                
                if (active == 0 && time == app.TimeEditFieldC.Value)
                    app.StatusLamp.Color = 'red';
                    app.TextArea.Value = "Didn't get up from bed yet";
                end
                
                im = snapshot(cam);
                detector = vision.CascadeObjectDetector;
                bbox = step(detector, im);
                videoOutMedicine = insertObjectAnnotation(im,'rectangle',medicine,'Medicine','Color','blue');
                videoOutBarthRoom = insertObjectAnnotation(videoOutMedicine,'rectangle',barthRoom,'Barthroom');
                videoOutBed = insertObjectAnnotation(videoOutBarthRoom,'rectangle',bed,'Bed','Color','green');
                videoOutEntrance = insertObjectAnnotation(videoOutBed,'rectangle',entrance,'Entrance','Color','white');
                videoOut = insertObjectAnnotation(videoOutEntrance,'rectangle',bbox,'Persons ','Color','red');
                                imshow(videoOut, 'Parent', app.UIAxes);
                [r,c] = size(bbox);
                app.HeadCountEditField.Value = r;
                
                if(r==1)
                    countBathroom = 0;
                    app.PositionTextArea.Value = "";
                    lastPos = 0;
                    active = 1;
                    bboxCor = [bbox(1,1),bbox(1,2),bbox(1,1)+bbox(1,3),bbox(1,2)+bbox(1,4)];
                    J = bbox(1,3);
                    app.JValueEditField.Value = J;
                    if(medicineCor(1,1)<bboxCor(1,1) && medicineCor(1,2)<bboxCor(1,2) && medicineCor(1,3)>bboxCor(1,3) && medicineCor(1,4)>bboxCor(1,4))
                        if (medicineJ>J)
                            app.PositionTextArea.Value = "Medicine";
                            lastPos = 1;
                            a = clock;
                            time = a(4)+(a(5)/100);
                            if(app.TimeEditFieldB.Value<time && (app.TimeEditFieldB.Value+0.15)>time)
                                medicineTake = 1;
                            end
                        end  
                    end
                    
                    if(barthRoomCor(1,1)<bboxCor(1,1) && barthRoomCor(1,2)<bboxCor(1,2) && barthRoomCor(1,3)>bboxCor(1,3) && barthRoomCor(1,4)>bboxCor(1,4))
                        if (barthRoomJ>J)
                            app.PositionTextArea.Value = "Bathroom";
                            lastPos = 2;
                        end  
                    end
                    
                    if(bedCor(1,1)<bboxCor(1,1) && bedCor(1,2)<bboxCor(1,2) && bedCor(1,3)>bboxCor(1,3) && bedCor(1,4)>bboxCor(1,4))
                        if (bedJ>J)
                            app.PositionTextArea.Value = "Bed";
                            lastPos = 3;
                            %
                                if (countBed == 0)
                                    a = clock;
                                    countBed = a(4)+(a(5)/100);                  
                                else
                                    a = clock;
                                    time = a(4)+(a(5)/100);
                                    dueBed = time-countBed;
                                    if  (dueBed > 0.1)
                                        active = 0;
                                    end
                        
                                end
                            %
                            
                        end 
                    else 
                        active = 1;   
                    end
                    
                    if(entranceCor(1,1)<bboxCor(1,1) && entranceCor(1,2)<bboxCor(1,2) && entranceCor(1,3)>bboxCor(1,3) && entranceCor(1,4)>bboxCor(1,4))
                        if (entranceJ>J)
                            app.PositionTextArea.Value = "Entrance";
                            lastPos = 4;
                        end  
                    end
                    
                end
                
                if (r==0)
                    app.PositionTextArea.Value = "Not detected";
                    if (lastPos == 2)
                        if (countBathroom == 0)
                            a = clock;
                            countBathroom = a(4)+(a(5)/100);                  
                        else
                            a = clock;
                            time = a(4)+(a(5)/100);
                            due = time-countBathroom;
                            if  (due > app.TimeEditFieldB.Value)
                                app.StatusLamp.Color = 'red';
                                app.TextArea.Value = "Didn't come out from the bathroom";
                            end
                        
                        end
                    end
                    
                end
            end
        end

        % Callback function
        function ResetButtonPushed(app, event)
            app.x=300;
        end

        % Value changed function: SwitchA
        function SwitchAValueChanged(app, event)
            value = app.SwitchA.Value;
            if value == 'On'
                app.x = 0;
            else
                app.x = 300;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditFieldReal = uieditfield(app.UIFigure, 'numeric');
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status ';
            app.StatusLamp = uilamp(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.TaskAMedicinePanel = uipanel(app.UIFigure);
            app.TaskAMedicinePanel.Title = 'Task A - Medicine';
            app.XEditFieldLabel = uilabel(app.TaskAMedicinePanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditFieldA = uieditfield(app.TaskAMedicinePanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.TaskAMedicinePanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditFieldA = uieditfield(app.TaskAMedicinePanel, 'numeric');
            app.WidthEditFieldLabel = uilabel(app.TaskAMedicinePanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditFieldA = uieditfield(app.TaskAMedicinePanel, 'numeric');
            app.HightEditFieldLabel = uilabel(app.TaskAMedicinePanel);
            app.HightEditFieldLabel.Text = 'Hight ';
            app.HightEditFieldA = uieditfield(app.TaskAMedicinePanel, 'numeric');
            app.SwitchA = uiswitch(app.TaskAMedicinePanel, 'toggle');
            app.SwitchA.ValueChangedFcn = createCallbackFcn(app, @SwitchAValueChanged, true);
            app.SwitchA.Value = 'On';
            app.TimeEditField_2Label = uilabel(app.TaskAMedicinePanel);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditFieldA = uieditfield(app.TaskAMedicinePanel, 'numeric');
            app.JValueLabel = uilabel(app.TaskAMedicinePanel);
            app.JValueLabel.Text = 'J Value';
            app.JValueA = uieditfield(app.TaskAMedicinePanel, 'numeric');
            app.TaskBBathroomPanel = uipanel(app.UIFigure);
            app.TaskBBathroomPanel.Title = 'Task B - Bathroom ';
            app.XEditField_3Label = uilabel(app.TaskBBathroomPanel);
            app.XEditField_3Label.Text = 'X';
            app.XEditFieldB = uieditfield(app.TaskBBathroomPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.TaskBBathroomPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditFieldB = uieditfield(app.TaskBBathroomPanel, 'numeric');
            app.WidthEditField_2Label = uilabel(app.TaskBBathroomPanel);
            app.WidthEditField_2Label.Text = 'Width';
            app.WidthEditFieldB = uieditfield(app.TaskBBathroomPanel, 'numeric');
            app.HightEditField_2Label = uilabel(app.TaskBBathroomPanel);
            app.HightEditField_2Label.Text = 'Hight ';
            app.HightEditFieldB = uieditfield(app.TaskBBathroomPanel, 'numeric');
            app.SwitchB = uiswitch(app.TaskBBathroomPanel, 'toggle');
            app.SwitchB.Value = 'On';
            app.TimeEditField_3Label = uilabel(app.TaskBBathroomPanel);
            app.TimeEditField_3Label.Text = 'Time';
            app.TimeEditFieldB = uieditfield(app.TaskBBathroomPanel, 'numeric');
            app.JValueLabel_2 = uilabel(app.TaskBBathroomPanel);
            app.JValueLabel_2.Text = 'J Value';
            app.JValueB = uieditfield(app.TaskBBathroomPanel, 'numeric');
            app.TaskCBedPanel = uipanel(app.UIFigure);
            app.TaskCBedPanel.Title = 'Task C - Bed';
            app.XEditField_3Label_2 = uilabel(app.TaskCBedPanel);
            app.XEditField_3Label_2.Text = 'X';
            app.XEditFieldC = uieditfield(app.TaskCBedPanel, 'numeric');
            app.YEditField_2Label_2 = uilabel(app.TaskCBedPanel);
            app.YEditField_2Label_2.Text = 'Y';
            app.YEditFieldC = uieditfield(app.TaskCBedPanel, 'numeric');
            app.WidthEditField_2Label_2 = uilabel(app.TaskCBedPanel);
            app.WidthEditField_2Label_2.Text = 'Width';
            app.WidthEditFieldC = uieditfield(app.TaskCBedPanel, 'numeric');
            app.HightEditField_2Label_2 = uilabel(app.TaskCBedPanel);
            app.HightEditField_2Label_2.Text = 'Hight ';
            app.HightEditFieldC = uieditfield(app.TaskCBedPanel, 'numeric');
            app.SwitchC = uiswitch(app.TaskCBedPanel, 'toggle');
            app.SwitchC.Value = 'On';
            app.TimeEditField_3Label_2 = uilabel(app.TaskCBedPanel);
            app.TimeEditField_3Label_2.Text = 'Time';
            app.TimeEditFieldC = uieditfield(app.TaskCBedPanel, 'numeric');
            app.JValueLabel_3 = uilabel(app.TaskCBedPanel);
            app.JValueLabel_3.Text = 'J Value';
            app.JValueC = uieditfield(app.TaskCBedPanel, 'numeric');
            app.TaskDEntrancePanel = uipanel(app.UIFigure);
            app.TaskDEntrancePanel.Title = 'Task D - Entrance';
            app.XEditField_3Label_3 = uilabel(app.TaskDEntrancePanel);
            app.XEditField_3Label_3.Text = 'X';
            app.XEditFieldD = uieditfield(app.TaskDEntrancePanel, 'numeric');
            app.YEditField_2Label_3 = uilabel(app.TaskDEntrancePanel);
            app.YEditField_2Label_3.Text = 'Y';
            app.YEditFieldD = uieditfield(app.TaskDEntrancePanel, 'numeric');
            app.WidthEditField_2Label_3 = uilabel(app.TaskDEntrancePanel);
            app.WidthEditField_2Label_3.Text = 'Width';
            app.WidthEditFieldD = uieditfield(app.TaskDEntrancePanel, 'numeric');
            app.HightEditField_2Label_3 = uilabel(app.TaskDEntrancePanel);
            app.HightEditField_2Label_3.Text = 'Hight ';
            app.HightEditFieldD = uieditfield(app.TaskDEntrancePanel, 'numeric');
            app.SwitchD = uiswitch(app.TaskDEntrancePanel, 'toggle');
            app.SwitchD.Value = 'On';
            app.TimeEditField_3Label_3 = uilabel(app.TaskDEntrancePanel);
            app.TimeEditField_3Label_3.Text = 'Time';
            app.TimeEditFieldD = uieditfield(app.TaskDEntrancePanel, 'numeric');
            app.JValueLabel_4 = uilabel(app.TaskDEntrancePanel);
            app.JValueLabel_4.Text = 'J Value';
            app.JValueD = uieditfield(app.TaskDEntrancePanel, 'numeric');
            app.JValueEditFieldLabel = uilabel(app.UIFigure);
            app.JValueEditFieldLabel.Text = 'J Value ';
            app.JValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeadCountEditFieldLabel = uilabel(app.UIFigure);
            app.HeadCountEditFieldLabel.Text = 'Head Count ';
            app.HeadCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.PositionTextAreaLabel = uilabel(app.UIFigure);
            app.PositionTextAreaLabel.Text = 'Position';
            app.PositionTextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.MedicineEditFieldLabel = uilabel(app.UIFigure);
            app.MedicineEditFieldLabel.Text = 'Medicine';
            app.Medicine = uieditfield(app.UIFigure, 'numeric');
            app.ActiveEditFieldLabel = uilabel(app.UIFigure);
            app.ActiveEditFieldLabel.Text = 'Active';
            app.Active = uieditfield(app.UIFigure, 'numeric');
            app.M = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = J4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end